classdef miniporject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImportButton_2           matlab.ui.control.Button
        filename2EditField       matlab.ui.control.EditField
        filename2EditFieldLabel  matlab.ui.control.Label
        PlotButton               matlab.ui.control.StateButton
        ImportButton             matlab.ui.control.Button
        filenameEditField        matlab.ui.control.EditField
        filenameEditFieldLabel   matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function filenameEditFieldValueChanged(app, event)
            value = app.filenameEditField.Value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename]=uigetfile('.wav');
            app.filenameEditField.Value = filename;
            figure(app.UIFigure);
            [filename, Fs] = audioread(app.filenameEditField.Value);
            %yMono = sum(data, 2) / size(data, 2);
            %t = linspace(0,length(filename)/Fs,length(filename));
            plot(app.UIAxes,filename)
        end

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
            %value = app.PlotButton.Value;
            [data1, Fs] = audioread(app.filenameEditField.Value);
            [data2, Fs] = audioread(app.filename2EditField.Value);
            %t = linspace(0,length(data)/Fs,length(data));
            data1_seg = data1(44100*10:44100*20);
            data2_seg = data2(44100*10:44100*20);
            data = data1_seg+data2_seg;
            Fs0 = length(data)
            data_fft = fft(data);
            %plot(app.UIAxes2,data_fft)
            plot(app.UIAxes2,real(data_fft))
            Fs1 = length(Fs)
            
            load handel.mat

            filename1 = 'handel.wav';
            audiowrite(filename1,real(data),44100);

            Fs2 = length(data_fft)
        end

        % Callback function
        function AddButtonPushed(app, event)
            %[filename] = uigetfile('.wav');   
            %varlist = who('-wav',rawdata1); 
            app.wavListBoxLabel.Items = {'filename'};
            %app.VariablesyaxisListBox.Items = varlist;
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            [filename]=uigetfile('.wav');
            app.filename2EditField.Value = filename;
            figure(app.UIFigure);
            [filename, Fs] = audioread(app.filename2EditField.Value);
            %yMono = sum(data, 2) / size(data, 2);
            %t = linspace(0,length(filename)/Fs,length(filename));
            plot(app.UIAxes_2,filename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '1kHz-fft')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Audio 2')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name :';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PlotButton = uibutton(app.UIFigure, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.filename2EditFieldLabel = uilabel(app.UIFigure);
            app.filename2EditFieldLabel.Text = 'file name 2 :';
            app.filename2EditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton_2 = uibutton(app.UIFigure, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniporject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end